classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EstudiodePropulsionUIFigure     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CargadedatosTab                 matlab.ui.container.Tab
        CargaDatosSmartwheelButton      matlab.ui.control.Button
        CargaDatosKinescanButton        matlab.ui.control.Button
        RealizarclculosButton           matlab.ui.control.Button
        PosicionesTab                   matlab.ui.container.Tab
        Tronco                          matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        Hombro                          matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        Codo                            matlab.ui.control.Button
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
        Muneca                          matlab.ui.control.Button
        UIAxes_10                       matlab.ui.control.UIAxes
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_12                       matlab.ui.control.UIAxes
        Button                          matlab.ui.control.Button
        BalanceolateralderechoLabel     matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_9                        matlab.ui.control.Button
        Button_10                       matlab.ui.control.Button
        Button_11                       matlab.ui.control.Button
        TorsinhaciaizquierdaLabel       matlab.ui.control.Label
        ExtensinhaciadetrsLabel         matlab.ui.control.Label
        Button_12                       matlab.ui.control.Button
        AdduccinLabel                   matlab.ui.control.Label
        HaciaadelanteLabel              matlab.ui.control.Label
        RotacininternaLabel             matlab.ui.control.Label
        EstevalorsiempreesceroLabel     matlab.ui.control.Label
        RotacininternaLabel_2           matlab.ui.control.Label
        FlexinLabel                     matlab.ui.control.Label
        DesviacinulnarLabel             matlab.ui.control.Label
        RotacininternaLabel_3           matlab.ui.control.Label
        FlexinLabel_2                   matlab.ui.control.Label
        FuerzasTab                      matlab.ui.container.Tab
        Hombro_2                        matlab.ui.control.Button
        UIAxes_16                       matlab.ui.control.UIAxes
        UIAxes_17                       matlab.ui.control.UIAxes
        UIAxes_18                       matlab.ui.control.UIAxes
        Codo_2                          matlab.ui.control.Button
        UIAxes_19                       matlab.ui.control.UIAxes
        UIAxes_20                       matlab.ui.control.UIAxes
        UIAxes_21                       matlab.ui.control.UIAxes
        Muneca_2                        matlab.ui.control.Button
        UIAxes_22                       matlab.ui.control.UIAxes
        UIAxes_23                       matlab.ui.control.UIAxes
        UIAxes_24                       matlab.ui.control.UIAxes
        MomentosTab                     matlab.ui.container.Tab
        Hombro_3                        matlab.ui.control.Button
        UIAxes_25                       matlab.ui.control.UIAxes
        UIAxes_26                       matlab.ui.control.UIAxes
        UIAxes_27                       matlab.ui.control.UIAxes
        Codo_3                          matlab.ui.control.Button
        UIAxes_28                       matlab.ui.control.UIAxes
        UIAxes_29                       matlab.ui.control.UIAxes
        UIAxes_30                       matlab.ui.control.UIAxes
        Muneca_3                        matlab.ui.control.Button
        UIAxes_31                       matlab.ui.control.UIAxes
        UIAxes_32                       matlab.ui.control.UIAxes
        UIAxes_33                       matlab.ui.control.UIAxes
        InformeTab                      matlab.ui.container.Tab
        GenerarinformeButton_2          matlab.ui.control.Button
        NombredelpacienteEditFieldLabel  matlab.ui.control.Label
        NombredelpacienteEditField      matlab.ui.control.EditField
        FechadelestudioEditFieldLabel   matlab.ui.control.Label
        FechadelestudioEditField        matlab.ui.control.EditField
        HNCLabel                        matlab.ui.control.Label
        HNCEditField                    matlab.ui.control.EditField
        PlantaEditFieldLabel            matlab.ui.control.Label
        PlantaEditField                 matlab.ui.control.EditField
        NiveldelesionEditFieldLabel     matlab.ui.control.Label
        NiveldelesionEditField          matlab.ui.control.EditField
        ASIAEditFieldLabel              matlab.ui.control.Label
        ASIAEditField                   matlab.ui.control.EditField
        CodigopacienteEditFieldLabel    matlab.ui.control.Label
        CodigopacienteEditField         matlab.ui.control.EditField
        PesoEditFieldLabel              matlab.ui.control.Label
        PesoEditField                   matlab.ui.control.EditField
        AlturaEditFieldLabel            matlab.ui.control.Label
        AlturaEditField                 matlab.ui.control.EditField
        EdadEditFieldLabel              matlab.ui.control.Label
        EdadEditField                   matlab.ui.control.EditField
        MotivodeconsultaEditFieldLabel  matlab.ui.control.Label
        MotivodeconsultaEditField       matlab.ui.control.EditField
        FacultativosolicitanteEditFieldLabel  matlab.ui.control.Label
        FacultativosolicitanteEditField  matlab.ui.control.EditField
        DATOSDELPACIENTELabel           matlab.ui.control.Label
        DATOSDELESTUDIOLabel            matlab.ui.control.Label
        DescripcindelensayoEditFieldLabel  matlab.ui.control.Label
        DescripcindelensayoEditField    matlab.ui.control.EditField
        InformedelestudioEditFieldLabel  matlab.ui.control.Label
        InformedelestudioEditField      matlab.ui.control.EditField
    end


    properties (Access = public)
        Property % Description
        filename1
        pathname1
        filename2
        pathname2
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.UIAxes.Visible='off';
           app.UIAxes_2.Visible='off';
           app.UIAxes_3.Visible='off';
           app.UIAxes_4.Visible='off';
           app.UIAxes_5.Visible='off';
           app.UIAxes_6.Visible='off';
           app.UIAxes_7.Visible='off';
           app.UIAxes_8.Visible='off';
           app.UIAxes_9.Visible='off';
           app.UIAxes_10.Visible='off';
           app.UIAxes_11.Visible='off';
           app.UIAxes_12.Visible='off';
           app.UIAxes_16.Visible='off';
           app.UIAxes_17.Visible='off';
           app.UIAxes_18.Visible='off';
           app.UIAxes_19.Visible='off';
           app.UIAxes_20.Visible='off';
           app.UIAxes_21.Visible='off';
           app.UIAxes_22.Visible='off';
           app.UIAxes_23.Visible='off';
           app.UIAxes_24.Visible='off';
           app.UIAxes_25.Visible='off';
           app.UIAxes_26.Visible='off';
           app.UIAxes_27.Visible='off';
           app.UIAxes_28.Visible='off';
           app.UIAxes_29.Visible='off';
           app.UIAxes_30.Visible='off';
           app.UIAxes_31.Visible='off';
           app.UIAxes_32.Visible='off';
           app.UIAxes_33.Visible='off';
           app.Button.Visible='off';
           app.Button_2.Visible='off';
           app.Button_3.Visible='off';
           app.Button_4.Visible='off';
           app.Button_5.Visible='off';
           app.Button_6.Visible='off';
           app.Button_7.Visible='off';
           app.Button_8.Visible='off';
           app.Button_9.Visible='off';
           app.Button_10.Visible='off';
           app.Button_11.Visible='off';
           app.Button_12.Visible='off';
           app.BalanceolateralderechoLabel.Visible='off';
           app.TorsinhaciaizquierdaLabel.Visible='off';
           app.ExtensinhaciadetrsLabel.Visible='off';
           app.AdduccinLabel.Visible='off';
           app.HaciaadelanteLabel.Visible='off';
           app.RotacininternaLabel.Visible='off';
           app.EstevalorsiempreesceroLabel.Visible='off';
           app.RotacininternaLabel_2.Visible='off';
           app.FlexinLabel.Visible='off';
           app.DesviacinulnarLabel.Visible='off';
           app.RotacininternaLabel_3.Visible='off';
           app.FlexinLabel_2.Visible='off';
           makeDOMCompilable();
        end

        % Button pushed function: CargaDatosKinescanButton
        function CargaDatosKinescanButtonPushed(app, event)
            [app.filename2,app.pathname2] = uigetfile('*.txt','Selecciona los datos CINEMATICOS a analizar.')
        end

        % Button pushed function: CargaDatosSmartwheelButton
        function CargaDatosSmartwheelButtonPushed(app, event)
            [app.filename1,app.pathname1] = uigetfile('*.csv','Selecciona los datos CINETICOS a analizar.')        
        end

        % Size changed function: CargadedatosTab
        function CargadedatosTabSizeChanged(app, event)
            position = app.CargadedatosTab.Position;
        end

        % Button pushed function: Codo
        function CodoButtonPushed(app, event)
            load codo.mat
            app.UIAxes_7.Visible='on';
            app.Button_5.Visible='on';
            app.Button_6.Visible='on';
            app.Button_10.Visible='on';
            hold(app.UIAxes_7)
            plot(app.UIAxes_7,escala_normal,angulos_codo.media_alfa,'k');
            plot(app.UIAxes_7,escala_normal,angulos_codo.media_alfa+angulos_codo.desviacion_alfa,'r');
            plot(app.UIAxes_7,escala_normal,angulos_codo.media_alfa-angulos_codo.desviacion_alfa,'b');
            set (app.UIAxes_7, 'Xlim',[0 1]);
            zoom (app.UIAxes_7,'on');
            set (app.UIAxes_7, 'Ylim',[-6 6],'Ytick',[-6 -4 -2 0 2 4 6]);
                        
            app.UIAxes_8.Visible='on';
            hold(app.UIAxes_8)
            plot(app.UIAxes_8,escala_normal,angulos_codo.media_beta,'k');
            plot(app.UIAxes_8,escala_normal,angulos_codo.media_beta+angulos_codo.desviacion_beta,'r');
            plot(app.UIAxes_8,escala_normal,angulos_codo.media_beta-angulos_codo.desviacion_beta,'b');
            set (app.UIAxes_8, 'Ylim',[40 120],'Ytick',[40 60 80 100 120]);
            set (app.UIAxes_8, 'Xlim',[0 1]);
            zoom (app.UIAxes_8,'on');
            
            app.UIAxes_9.Visible='on';
            hold(app.UIAxes_9)
            plot(app.UIAxes_9,escala_normal,angulos_codo.media_gamma,'k');
            plot(app.UIAxes_9,escala_normal,angulos_codo.media_gamma+angulos_codo.desviacion_gamma,'r');
            plot(app.UIAxes_9,escala_normal,angulos_codo.media_gamma-angulos_codo.desviacion_gamma,'b');
            set (app.UIAxes_9, 'Ylim',[0 120],'Ytick',[0 30 60 90 120]);
            set (app.UIAxes_9, 'Xlim',[0 1]);
            zoom (app.UIAxes_9,'on');
        end

        % Button pushed function: Codo_2
        function Codo_2ButtonPushed(app, event)
            load codo.mat
            app.UIAxes_19.Visible='on';
            hold(app.UIAxes_19)
            plot(app.UIAxes_19,escala_normal,fuerzas_codo.media_f_codo_x,'k');
            plot(app.UIAxes_19,escala_normal,fuerzas_codo.media_f_codo_x+fuerzas_codo.desviacion_f_codo_x,'r');
            plot(app.UIAxes_19,escala_normal,fuerzas_codo.media_f_codo_x-fuerzas_codo.desviacion_f_codo_x,'b');
            set (app.UIAxes_19, 'Ylim',[-50 50],'Ytick',[-50 -25 0 25 50]);
            set (app.UIAxes_19, 'Xlim',[0 1]);
            zoom (app.UIAxes_19,'on');
            
            app.UIAxes_20.Visible='on';
            hold(app.UIAxes_20)
            plot(app.UIAxes_20,escala_normal,fuerzas_codo.media_f_codo_y,'k');
            plot(app.UIAxes_20,escala_normal,fuerzas_codo.media_f_codo_y+fuerzas_codo.desviacion_f_codo_y,'r');
            plot(app.UIAxes_20,escala_normal,fuerzas_codo.media_f_codo_y-fuerzas_codo.desviacion_f_codo_y,'b');
            set (app.UIAxes_20, 'Ylim',[-50 50],'Ytick',[-50 -25 0 25 50]);
            set (app.UIAxes_20, 'Xlim',[0 1]);
            zoom (app.UIAxes_20,'on');
            
            app.UIAxes_21.Visible='on';
            hold(app.UIAxes_21)
            plot(app.UIAxes_21,escala_normal,fuerzas_codo.media_f_codo_z,'k');
            plot(app.UIAxes_21,escala_normal,fuerzas_codo.media_f_codo_z+fuerzas_codo.desviacion_f_codo_z,'r');
            plot(app.UIAxes_21,escala_normal,fuerzas_codo.media_f_codo_z-fuerzas_codo.desviacion_f_codo_z,'b');
            set (app.UIAxes_21, 'Ylim',[-50 50],'Ytick',[-50 -25 0 25 50]);
            set (app.UIAxes_21, 'Xlim',[0 1]);
            zoom (app.UIAxes_21,'on');
        end

        % Button pushed function: Hombro
        function HombroButtonPushed(app, event)
            load hombro.mat
            app.UIAxes_4.Visible='on';
            app.Button_2.Visible='on';
            app.Button_4.Visible='on';
            app.Button_11.Visible='on';
            hold(app.UIAxes_4)
            plot(app.UIAxes_4,escala_normal,angulos_hombro.media_alfa,'k');
            plot(app.UIAxes_4,escala_normal,angulos_hombro.media_alfa+angulos_hombro.desviacion_alfa,'r');
            plot(app.UIAxes_4,escala_normal,angulos_hombro.media_alfa-angulos_hombro.desviacion_alfa,'b');
            set (app.UIAxes_4, 'Ylim',[-50 0],'Ytick',[-50 -40 -30 -20 -10 0]);
            set (app.UIAxes_4, 'Xlim',[0 1]);
            zoom (app.UIAxes_4,'on');
            
            app.UIAxes_5.Visible='on';
            hold(app.UIAxes_5)
            plot(app.UIAxes_5,escala_normal,angulos_hombro.media_beta,'k');
            plot(app.UIAxes_5,escala_normal,angulos_hombro.media_beta+angulos_hombro.desviacion_beta,'r');
            plot(app.UIAxes_5,escala_normal,angulos_hombro.media_beta-angulos_hombro.desviacion_beta,'b');
            set (app.UIAxes_5, 'Ylim',[-100 100],'Ytick',[-100 -50 0 50 100]);
            set (app.UIAxes_5, 'Xlim',[0 1]);
            zoom (app.UIAxes_5,'on');
            
            app.UIAxes_6.Visible='on';
            hold(app.UIAxes_6)
            plot(app.UIAxes_6,escala_normal,angulos_hombro.media_gamma,'k');
            plot(app.UIAxes_6,escala_normal,angulos_hombro.media_gamma+angulos_hombro.desviacion_gamma,'r');
            plot(app.UIAxes_6,escala_normal,angulos_hombro.media_gamma-angulos_hombro.desviacion_gamma,'b');
            set (app.UIAxes_6, 'Ylim',[-50 100],'Ytick',[-50 -20 10 40 70 100]);
            set (app.UIAxes_6, 'Xlim',[0 1]);
            zoom (app.UIAxes_6,'on');
        end

        % Button pushed function: Hombro_2
        function Hombro_2ButtonPushed(app, event)
            load hombro.mat
            app.UIAxes_16.Visible='on';
            hold(app.UIAxes_16)
            plot(app.UIAxes_16,escala_normal,fuerzas_hombro.media_f_hombro_x,'k');
            plot(app.UIAxes_16,escala_normal,fuerzas_hombro.media_f_hombro_x+fuerzas_hombro.desviacion_f_hombro_x,'r');
            plot(app.UIAxes_16,escala_normal,fuerzas_hombro.media_f_hombro_x-fuerzas_hombro.desviacion_f_hombro_x,'b');
            set (app.UIAxes_16, 'Ylim',[-50 110],'Ytick',[-50 -10 30 70 110]);
            set (app.UIAxes_16, 'Xlim',[0 1]);
            zoom (app.UIAxes_16,'on');
            
            app.UIAxes_17.Visible='on';
            hold(app.UIAxes_17)
            plot(app.UIAxes_17,escala_normal,fuerzas_hombro.media_f_hombro_y,'k');
            plot(app.UIAxes_17,escala_normal,fuerzas_hombro.media_f_hombro_y+fuerzas_hombro.desviacion_f_hombro_y,'r');
            plot(app.UIAxes_17,escala_normal,fuerzas_hombro.media_f_hombro_y-fuerzas_hombro.desviacion_f_hombro_y,'b');
            set (app.UIAxes_17, 'Ylim',[-90 60],'Ytick',[-90 -60 -30 0 30 60]);
            set (app.UIAxes_17, 'Xlim',[0 1]);
            zoom (app.UIAxes_17,'on');
            
            app.UIAxes_18.Visible='on';
            hold(app.UIAxes_18)
            plot(app.UIAxes_18,escala_normal,fuerzas_hombro.media_f_hombro_z,'k');
            plot(app.UIAxes_18,escala_normal,fuerzas_hombro.media_f_hombro_z+fuerzas_hombro.desviacion_f_hombro_z,'r');
            plot(app.UIAxes_18,escala_normal,fuerzas_hombro.media_f_hombro_z-fuerzas_hombro.desviacion_f_hombro_z,'b');
            set (app.UIAxes_18, 'Ylim',[-50 50],'Ytick',[-50 -30 -10 10 30 50]);
            set (app.UIAxes_18, 'Xlim',[0 1]);
            zoom (app.UIAxes_18,'on');
        end

        % Button pushed function: Muneca
        function MunecaButtonPushed(app, event)
            load muneca.mat
            app.UIAxes_10.Visible='on';
            app.Button_7.Visible='on';
            app.Button_8.Visible='on';
            app.Button_9.Visible='on';
            hold(app.UIAxes_10)
            plot(app.UIAxes_10, escala_normal,angulos_muneca.media_alfa,'k');
            plot(app.UIAxes_10, escala_normal,angulos_muneca.media_alfa+angulos_muneca.desviacion_alfa,'r');
            plot(app.UIAxes_10, escala_normal,angulos_muneca.media_alfa-angulos_muneca.desviacion_alfa,'b');
            set (app.UIAxes_10, 'Ylim',[-45 55],'Ytick',[-45 -25 -5 15 35 55]);
            set (app.UIAxes_10, 'Xlim',[0 1]);
            zoom (app.UIAxes_10,'on');
            
            app.UIAxes_11.Visible='on';
            hold(app.UIAxes_11)
            plot(app.UIAxes_11,escala_normal,angulos_muneca.media_beta,'k');
            plot(app.UIAxes_11,escala_normal,angulos_muneca.media_beta+angulos_muneca.desviacion_beta,'r');
            plot(app.UIAxes_11,escala_normal,angulos_muneca.media_beta-angulos_muneca.desviacion_beta,'b');
            set (app.UIAxes_11, 'Ylim',[-45 55],'Ytick',[-45 -25 -5 15 35 55]);
            set (app.UIAxes_11, 'Xlim',[0 1]);
            zoom (app.UIAxes_11,'on');
            
            app.UIAxes_12.Visible='on';
            hold(app.UIAxes_12)
            plot(app.UIAxes_12,escala_normal,angulos_muneca.media_gamma,'k');
            plot(app.UIAxes_12,escala_normal,angulos_muneca.media_gamma+angulos_muneca.desviacion_gamma,'r');
            plot(app.UIAxes_12,escala_normal,angulos_muneca.media_gamma-angulos_muneca.desviacion_gamma,'b');
            set (app.UIAxes_12, 'Ylim',[-45 55],'Ytick',[-45 -25 -5 15 35 55]);
            set (app.UIAxes_12, 'Xlim',[0 1]);
            zoom (app.UIAxes_12,'on');
        end

        % Button pushed function: Muneca_2
        function Muneca_2ButtonPushed(app, event)
            load muneca.mat
            app.UIAxes_22.Visible='on';
            hold(app.UIAxes_22)
            plot(app.UIAxes_22,escala_normal,fuerzas_muneca.media_f_muneca_x,'k');
            plot(app.UIAxes_22,escala_normal,fuerzas_muneca.media_f_muneca_x+fuerzas_muneca.desviacion_f_muneca_x,'r');
            plot(app.UIAxes_22,escala_normal,fuerzas_muneca.media_f_muneca_x-fuerzas_muneca.desviacion_f_muneca_x,'b');
            pan (app.UIAxes_22, 'on');
            set (app.UIAxes_22, 'Ylim',[-50 50],'Ytick',[-50 -25 0 25 50]);
            set (app.UIAxes_22, 'Xlim',[0 1]);
            zoom (app.UIAxes_22,'on');
            
            app.UIAxes_23.Visible='on';
            hold(app.UIAxes_23)
            plot(app.UIAxes_23,escala_normal,fuerzas_muneca.media_f_muneca_y,'k');
            plot(app.UIAxes_23,escala_normal,fuerzas_muneca.media_f_muneca_y+fuerzas_muneca.desviacion_f_muneca_y,'r');
            plot(app.UIAxes_23,escala_normal,fuerzas_muneca.media_f_muneca_y-fuerzas_muneca.desviacion_f_muneca_y,'b');
            set (app.UIAxes_23, 'Ylim',[-50 50],'Ytick',[-50 -25 0 25 50]);
            set (app.UIAxes_23, 'Xlim',[0 1]);
            zoom (app.UIAxes_23,'on');
            
            app.UIAxes_24.Visible='on';
            hold(app.UIAxes_24)
            plot(app.UIAxes_24,escala_normal,fuerzas_muneca.media_f_muneca_z,'k');
            plot(app.UIAxes_24,escala_normal,fuerzas_muneca.media_f_muneca_z+fuerzas_muneca.desviacion_f_muneca_z,'r');
            plot(app.UIAxes_24,escala_normal,fuerzas_muneca.media_f_muneca_z-fuerzas_muneca.desviacion_f_muneca_z,'b');
            set (app.UIAxes_24, 'Ylim',[-50 50], 'Ytick',[-50 -25 0 25 50]);
            set (app.UIAxes_24, 'Xlim',[0 1]);
            zoom (app.UIAxes_24,'on');
        end

        % Button pushed function: RealizarclculosButton
        function RealizarclculosButtonPushed2(app, event)
             
            [forces,frames,FSW,tiempoSW,dt]= DatosCineticos(app.filename1,app.pathname1);
            
            % Creacion del vector step
            
            [step,cont_up,cont_down] = funcion_paso(forces,frames);
            MarcadoresFinales=DatosCinematicos(app.filename2, app.pathname2, tiempoSW);
            
            %-----------------ANTROPOMETRIA-------------------------------------------%
            [MomentosInercia,CentrosMasa,Pesos]= Antropometria(app.pathname2,frames,MarcadoresFinales); 
            
            %--------------CALCULO DE LOS SISTEMAS DE REFERENCIA LOCALES--------------%
            [rot_MG_A,rot_MA_G, mnd_ML, amd_ML]= SRLMano(frames,MarcadoresFinales,CentrosMasa);
            [rot_AG_A,rot_AA_G,j_anteb_EU, amd_AL, acd_AL]= SRLAnteb (frames,MarcadoresFinales,CentrosMasa);
            [rot_BG_A,rot_BA_G,acd_BL, agd_BL]= SRLBrazo(frames, MarcadoresFinales, j_anteb_EU,CentrosMasa);
            [rot_TG_A,rot_TA_G]= SRLTronco(frames, MarcadoresFinales);
            
            %---CALCULO DE LOS ANGULOS DE EULER PARA LA REPRESENTACION CINEMATICA-----%
                  
            [gamma_tr, beta_tr, alfa_tr]=AngulosTronco (frames,rot_TA_G);
            [gamma_rsho, beta_rsho, alfa_rsho]=AngulosHombro(frames,rot_TA_G, rot_BA_G);
            [gamma_relb, beta_relb, alfa_relb]=AngulosCodo(frames, rot_BA_G, rot_AA_G);
            [gamma_rwr, beta_rwr, alfa_rwr]=AngulosMuneca(frames, rot_AA_G, rot_MA_G);
      
            %------------------CINETICA DE LAS ARTICULACIONES-------------------------%
          
            
            [gamma_M_grad, beta_M_grad, alfa_M_grad, phi_M]=CineticaMano(frames,rot_MG_A,dt);
            [gamma_A_grad, beta_A_grad, alfa_A_grad,phi_A,omega_A]=CineticaAnteb (frames, rot_AG_A,dt);
            [gamma_B_grad, beta_B_grad, alfa_B_grad,phi_B,omega_B]=CineticaBrazo(frames, rot_BG_A, dt);
           
            %-----------------------CALCULOS CINETICOS--------------------------------%
            
            [f_muneca,f_codo,f_hombro,rD_L_mano,rP_L_mano,rD_L_anteb,rP_L_anteb,rD_L_brazo,rP_L_brazo]=Fuerzas (frames,FSW,CentrosMasa,Pesos, dt, rot_MG_A, rot_AG_A, rot_BG_A, rot_TG_A );
            [m_muneca, m_codo, m_hombro]=Momentos(frames,mnd_ML, amd_ML, amd_AL, acd_AL, acd_BL, agd_BL,MomentosInercia, phi_M,rD_L_mano,rP_L_mano, rot_MA_G, rot_AG_A, phi_A,omega_A, rD_L_anteb,rP_L_anteb, rot_AA_G, rot_BG_A,phi_B,omega_B,rD_L_brazo, rP_L_brazo, rot_BA_G, rot_TG_A);
            
            Normalizacion_ciclos;
            graficas;
            
            
            save ('angulostronco.mat','escala_normal','angulos_tronco') 
            save ('hombro.mat','escala_normal','angulos_hombro','fuerzas_hombro','momentos_hombro')
            save ('codo.mat','escala_normal','angulos_codo','fuerzas_codo','momentos_codo')
            save ('muneca.mat','escala_normal','angulos_muneca','fuerzas_muneca','momentos_muneca')
            save ('maximosyminimos.mat','ymax_alpha_tr','ymin_alpha_tr','ymax_beta_tr','ymin_beta_tr','ymax_gamma_tr','ymin_gamma_tr',...
'ymax_momentox_hombro','ymin_momentox_hombro','ymax_momentoy_hombro','ymin_momentoy_hombro','ymax_momentoz_hombro','ymin_momentoz_hombro',...
'ymax_fuerzax_hombro','ymin_fuerzax_hombro','ymax_fuerzay_hombro','ymin_fuerzay_hombro','ymax_fuerzaz_hombro','ymin_fuerzaz_hombro',...
'ymax_alpha_hombro','ymin_alpha_hombro','ymax_beta_hombro','ymin_beta_hombro','ymax_gamma_hombro','ymin_gamma_hombro',...
'ymax_momentox_codo','ymin_momentox_codo','ymax_momentoy_codo','ymin_momentoy_codo','ymax_momentoz_codo','ymin_momentoz_codo',...
'ymax_fuerzax_codo','ymin_fuerzax_codo','ymax_fuerzay_codo','ymin_fuerzay_codo','ymax_fuerzaz_codo','ymin_fuerzaz_codo',...
'ymax_beta_codo','ymin_beta_codo','ymax_gamma_codo','ymin_gamma_codo',...
'ymax_momentox_muneca','ymin_momentox_muneca','ymax_momentoy_muneca','ymin_momentoy_muneca','ymax_momentoz_muneca','ymin_momentoz_muneca',...
'ymax_fuerzax_muneca','ymin_fuerzax_muneca','ymax_fuerzay_muneca','ymin_fuerzay_muneca','ymax_fuerzaz_muneca','ymin_fuerzaz_muneca',...
'ymax_alpha_muneca','ymin_alpha_muneca','ymax_beta_muneca','ymin_beta_muneca','ymax_gamma_muneca','ymin_gamma_muneca')
            
            
        end

        % Callback function
        function RealizarclculosButtonPushed(app, event)
             
          
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Button pushed function: Tronco
        function TroncoButtonPushed(app, event)
            load angulostronco.mat
            app.UIAxes.Visible='on';
            app.Button.Visible='on';
            app.Button_3.Visible='on';
            app.Button_12.Visible='on';
            hold(app.UIAxes)
            plot(app.UIAxes,escala_normal,angulos_tronco.media_alfa,'k');
            plot(app.UIAxes,escala_normal,angulos_tronco.media_alfa+angulos_tronco.desviacion_alfa,'r');
            plot(app.UIAxes,escala_normal,angulos_tronco.media_alfa-angulos_tronco.desviacion_alfa,'b');
            set (app.UIAxes, 'Ylim',[-6 6],'Ytick',[-6 -4 -2 0 2 4 6]);
            set (app.UIAxes, 'Xlim',[0 1]);
            zoom (app.UIAxes,'on');
            
            app.UIAxes_2.Visible='on';
            hold(app.UIAxes_2)
            plot(app.UIAxes_2,escala_normal,angulos_tronco.media_beta,'k');
            plot(app.UIAxes_2,escala_normal,angulos_tronco.media_beta+angulos_tronco.desviacion_beta,'r');
            plot(app.UIAxes_2,escala_normal,angulos_tronco.media_beta-angulos_tronco.desviacion_beta,'b');
            set (app.UIAxes_2, 'Ylim',[-6 6],'Ytick',[-6 -4 -2 0 2 4 6]);
            set (app.UIAxes_2, 'Xlim',[0 1]);
            zoom (app.UIAxes_2,'on');
            
            app.UIAxes_3.Visible='on';
            hold(app.UIAxes_3)
            plot(app.UIAxes_3,escala_normal,angulos_tronco.media_gamma,'k');
            plot(app.UIAxes_3,escala_normal,angulos_tronco.media_gamma+angulos_tronco.desviacion_gamma,'r');
            plot(app.UIAxes_3,escala_normal,angulos_tronco.media_gamma-angulos_tronco.desviacion_gamma,'b');
            pan (app.UIAxes_3, 'on');
            set (app.UIAxes_3, 'Ylim',[-5 5],'Ytick',[-5 -2.5 0 2.5 5]);
            set (app.UIAxes_3, 'Xlim',[0 1]);
            zoom (app.UIAxes_3,'on');
            
        end

        % Callback function
        function Tronco_2ButtonPushed(app, event)
             
        end

        % Callback function
        function GenerarinformeButtonPushed(app, event)

        end

        % Button pushed function: Hombro_3
        function Hombro_3ButtonPushed(app, event)
            load hombro.mat
            app.UIAxes_25.Visible='on';
            hold(app.UIAxes_25)
            plot(app.UIAxes_25,escala_normal,momentos_hombro.media_m_hombro_x,'k');
            plot(app.UIAxes_25,escala_normal,momentos_hombro.media_m_hombro_x+momentos_hombro.desviacion_m_hombro_x,'r');
            plot(app.UIAxes_25,escala_normal,momentos_hombro.media_m_hombro_x-momentos_hombro.desviacion_m_hombro_x,'b');
            set (app.UIAxes_25, 'Ylim',[-5 5],'Ytick',[-5 -2.5 0 2.5 5]);
            set (app.UIAxes_25, 'Xlim',[0 1]);
            zoom (app.UIAxes_25,'on');
            
            app.UIAxes_26.Visible='on';
            hold(app.UIAxes_26)
            plot(app.UIAxes_26,escala_normal,momentos_hombro.media_m_hombro_y,'k');
            plot(app.UIAxes_26,escala_normal,momentos_hombro.media_m_hombro_y+momentos_hombro.desviacion_m_hombro_y,'r');
            plot(app.UIAxes_26,escala_normal,momentos_hombro.media_m_hombro_y-momentos_hombro.desviacion_m_hombro_y,'b');
            set (app.UIAxes_26, 'Ylim',[-10 10],'Ytick',[-10 -6 -2 0 2 6 10]);
            set (app.UIAxes_26, 'Xlim',[0 1]);
            zoom (app.UIAxes_26,'on');
            
            app.UIAxes_27.Visible='on';
            hold(app.UIAxes_27)
            plot(app.UIAxes_27,escala_normal,momentos_hombro.media_m_hombro_z,'k');
            plot(app.UIAxes_27,escala_normal,momentos_hombro.media_m_hombro_z+momentos_hombro.desviacion_m_hombro_z,'r');
            plot(app.UIAxes_27,escala_normal,momentos_hombro.media_m_hombro_z-momentos_hombro.desviacion_m_hombro_z,'b');
            set (app.UIAxes_27, 'Ylim',[-15 15],'Ytick',[-15 -10 -5 0 5 10 15]);
            set (app.UIAxes_27, 'Xlim',[0 1]);
            zoom (app.UIAxes_27,'on');
        end

        % Button pushed function: Codo_3
        function Codo_3ButtonPushed(app, event)
            load codo.mat
            app.UIAxes_28.Visible='on';
            hold(app.UIAxes_28)
            plot(app.UIAxes_28,escala_normal,momentos_codo.media_m_codo_x,'k');
            plot(app.UIAxes_28,escala_normal,momentos_codo.media_m_codo_x+momentos_codo.desviacion_m_codo_x,'r');
            plot(app.UIAxes_28,escala_normal,momentos_codo.media_m_codo_x-momentos_codo.desviacion_m_codo_x,'b');
            set (app.UIAxes_28, 'Ylim',[-4 4],'Ytick',[-4 -2 0 2 4]);
            set (app.UIAxes_28, 'Xlim',[0 1]);
            zoom (app.UIAxes_28,'on');
            
            app.UIAxes_29.Visible='on';
            hold(app.UIAxes_29)
            plot(app.UIAxes_29,escala_normal,momentos_codo.media_m_codo_y,'k');
            plot(app.UIAxes_29,escala_normal,momentos_codo.media_m_codo_y+momentos_codo.desviacion_m_codo_y,'r');
            plot(app.UIAxes_29,escala_normal,momentos_codo.media_m_codo_y-momentos_codo.desviacion_m_codo_y,'b');
            set (app.UIAxes_29, 'Ylim',[-2 2],'Ytick',[-2 -1 0 1 2]);
            set (app.UIAxes_29, 'Xlim',[0 1]);
            zoom (app.UIAxes_29,'on');
            
            app.UIAxes_30.Visible='on';
            hold(app.UIAxes_30)
            plot(app.UIAxes_30,escala_normal,momentos_codo.media_m_codo_z,'k');
            plot(app.UIAxes_30,escala_normal,momentos_codo.media_m_codo_z+momentos_codo.desviacion_m_codo_z,'r');
            plot(app.UIAxes_30,escala_normal,momentos_codo.media_m_codo_z-momentos_codo.desviacion_m_codo_z,'b');
            set (app.UIAxes_30, 'Ylim',[-6 6],'Ytick',[-6 -4 -2 0 2 4 6]);
            set (app.UIAxes_30, 'Xlim',[0 1]);
            zoom (app.UIAxes_30,'on');
        end

        % Button pushed function: Muneca_3
        function Muneca_3ButtonPushed(app, event)
            load muneca.mat
            app.UIAxes_31.Visible='on';
            hold(app.UIAxes_31)
            plot(app.UIAxes_31,escala_normal,momentos_muneca.media_m_muneca_x,'k');
            plot(app.UIAxes_31,escala_normal,momentos_muneca.media_m_muneca_x+momentos_muneca.desviacion_m_muneca_x,'r');
            plot(app.UIAxes_31,escala_normal,momentos_muneca.media_m_muneca_x-momentos_muneca.desviacion_m_muneca_x,'b');
            set (app.UIAxes_31, 'Ylim',[-2 2],'Ytick',[-2 -1 0 1 2]);
            set (app.UIAxes_31, 'Xlim',[0 1]);
            zoom (app.UIAxes_31,'on');
            
            app.UIAxes_32.Visible='on';
            hold(app.UIAxes_32)
            plot(app.UIAxes_32,escala_normal,momentos_muneca.media_m_muneca_y,'k');
            plot(app.UIAxes_32,escala_normal,momentos_muneca.media_m_muneca_y+momentos_muneca.desviacion_m_muneca_y,'r');
            plot(app.UIAxes_32,escala_normal,momentos_muneca.media_m_muneca_y-momentos_muneca.desviacion_m_muneca_y,'b');
            set (app.UIAxes_32, 'Ylim',[-2 2],'Ytick',[-2 -1 0 1 2]);
            set (app.UIAxes_32, 'Xlim',[0 1]);
            zoom (app.UIAxes_32,'on');
            
            app.UIAxes_33.Visible='on';
            hold(app.UIAxes_33)
            plot(app.UIAxes_33,escala_normal,momentos_muneca.media_m_muneca_z,'k');
            plot(app.UIAxes_33,escala_normal,momentos_muneca.media_m_muneca_z+momentos_muneca.desviacion_m_muneca_z,'r');
            plot(app.UIAxes_33,escala_normal,momentos_muneca.media_m_muneca_z-momentos_muneca.desviacion_m_muneca_z,'b');
            set (app.UIAxes_33, 'Ylim',[-2 2],'Ytick',[-2 -1 0 1 2]);
            set (app.UIAxes_33, 'Xlim',[0 1]);
            zoom (app.UIAxes_33,'on');
        end

        % Size changed function: EstudiodePropulsionUIFigure
        function EstudiodePropulsionUIFigureSizeChanged(app, event)
            position = app.EstudiodePropulsionUIFigure.Position;
            position=[100 100 proportionalWidth proportionalHeight];
        end

        % Button pushed function: GenerarinformeButton_2
        function GenerarinformeButton_2Pushed(app, event)
            
            import mlreportgen.dom.* %import the DOM API
            import mlreportgen.report.*
            
            doc =Document ('propulsion', 'docx');   % create an empty PDF document. 'mydoc' es el nombre del .pdf
            
            %% Insert an image
            domObj=Image(which('Encabezado.png'));          % create an image object
            domObj.Height='40mm';
            domObj.Width='180mm';
            append(doc,domObj);
           
            chapter1=Paragraph('UNIDAD DE BIOMECANICA Y AYUDAS TECNICAS');
            chapter1.Style={HAlign('Center'),FontSize('16pt'), Bold(true)};
            chapter2=Paragraph('INFORME DE ESTUDIO DE PROPULSION DE SILLA DE RUEDAS');
            chapter2.Style={HAlign('Center'),FontSize('14pt'), Bold(true)};
            chapter3= Paragraph('EQUIPO DE ANALISIS CINEMATICO: KINESCAN');
            chapter3.Style={HAlign('Center'),FontSize('14pt'), Bold(true)};
            chapter4= Paragraph('EQUIPO DE ANALISIS CINETICO: SMARTWHEEL');
            chapter4.Style={HAlign('Center'),FontSize('14pt'), Bold(true)};
            chapter5= Paragraph('');
            chapter5.Style={HAlign('Center'),FontSize('14pt'), Bold(true), };
            lbr1=LineBreak();
            lbr2=LineBreak();

%           append (doc, tp);  %add some content
            append(doc,chapter1);
            append(doc,chapter2);
            append(doc,chapter3);
            append(doc,chapter4);
            append(doc,chapter5);
            append(doc,lbr1);
            append(doc,lbr2);
             
            %% Add a paragraph
            Fecha=app.FechadelestudioEditField.Value;
            textObj2= Text(Fecha);   % create a paragraph object
            paraObj2=Paragraph('                                                                                               Fecha del estudio:');
            append(paraObj2,textObj2);
            paraObj2.Style={HAlign('Left'), WhiteSpace('pre')};
            append(doc,paraObj2);     
            append(doc,LineBreak());
                   
            Nombre=app.NombredelpacienteEditField.Value;
            textObj1=Text(Nombre);
            paraObj1=Paragraph('Nombre del paciente: ');
            append(paraObj1,textObj1);
            textObj1.Style={Bold(false)};
            paraObj1.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj1);

            HNC=app.HNCEditField.Value;
            textObj3=Text(HNC);
            paraObj3=Paragraph('HNC: ');
            append(paraObj3,textObj3);
            textObj3.Style={Bold(false)};
            paraObj3.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj3);
            
            Planta=app.PlantaEditField.Value;
            textObj4=Text(Planta);
            paraObj4=Paragraph('Planta: ');
            append(paraObj4,textObj4);
            textObj4.Style={Bold(false)};
            paraObj4.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj4);

            Niveldelesion=app.NiveldelesionEditField.Value;
            textObj5=Text(Niveldelesion);
            paraObj5=Paragraph('Nivel de lesión: ');
            append(paraObj5,textObj5);
            textObj5.Style={Bold(false)};
            paraObj5.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj5);
            
            ASIA=app.ASIAEditField.Value;
            textObj6=Text(ASIA);
            paraObj6=Paragraph('ASIA: ');
            append(paraObj6,textObj6);
            textObj6.Style={Bold(false)};
            paraObj6.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj6);
           
            Codigo=app.CodigopacienteEditField.Value;
            textObj7=Text(Codigo);
            paraObj7=Paragraph('Código paciente: ');
            append(paraObj7,textObj7);
            textObj7.Style={Bold(false)};
            paraObj7.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj7);
            
            Peso=app.PesoEditField.Value;
            textObj8=Text(Peso);
            paraObj8=Paragraph('Peso(Kg.): ');
            append(paraObj8,textObj8);
            textObj8.Style={Bold(false)};
            paraObj8.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj8);
 
            Altura=app.AlturaEditField.Value;
            textObj9=Text(Altura);
            paraObj9=Paragraph('Altura (cm.): ');
            append(paraObj9,textObj9);
            textObj9.Style={Bold(false)};
            paraObj9.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj9);
  
            Edad=app.EdadEditField.Value;
            textObj10=Text(Edad);
            paraObj10=Paragraph('Edad: ');
            append(paraObj10,textObj10);
            textObj10.Style={Bold(false)};
            paraObj10.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj10);
            append(doc, LineBreak());
            
            Motivo=app.MotivodeconsultaEditField.Value;
            textObj11=Text(Motivo);
            paraObj11=Paragraph('Motivo de la consulta: ');
            append(paraObj11,textObj11);
            textObj11.Style={Bold(false)};
            paraObj11.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj11);
            append(doc, LineBreak());
            
            Facultativo=app.FacultativosolicitanteEditField.Value;
            textObj12=Text(Facultativo);
            paraObj12=Paragraph('Facultativo solicitante del estudio: ');
            append(paraObj12,textObj12);
            textObj12.Style={Bold(false)};
            paraObj12.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj12);
            append(doc, LineBreak());
           
            
            Descripcion=app.DescripcindelensayoEditField.Value;
            textObj13=Text(Descripcion);
            paraObj13=Paragraph('Descripción del ensayo: ');
            append(paraObj13,textObj13);
            textObj13.Style={Bold(false)};
            paraObj13.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj13);
            append(doc, LineBreak());
            append(doc,LineBreak());
            
            Informe=app.InformedelestudioEditField.Value;
            textObj101=Text(Informe);
            paraObj101=Paragraph('Informe del estudio: ');
            append(paraObj101,textObj101);
            textObj101.Style={Bold(false)};
            paraObj101.Style={HAlign('Left'), WhiteSpace('pre'),Bold(true)};
            append(doc,paraObj101);
            append(doc, LineBreak());
            append(doc,LineBreak());
            
            load maximosyminimos.mat
            paraObj102=Paragraph('Los resultados cinemáticos del ensayo son: ');
            append(doc,paraObj102); 
            paraObj102.Style={Bold(true)};
            append(doc,LineBreak());
            paraObj14=Paragraph('Ángulos medios de balanceo del tronco: ');
            append(doc,paraObj14);
            paraObj15=Paragraph('                 - Máximo ángulo de balanceo (grados): ');
            paraObj15.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj15=Text(ymax_alpha_tr);
            append(paraObj15,textObj15);
            append(doc, paraObj15);
            paraObj16=Paragraph('                 - Mínimo ángulo de balanceo (grados): ');
            paraObj16.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj16=Text(ymin_alpha_tr);
            append(paraObj16,textObj16);
            append(doc, paraObj16);    
            append(doc,LineBreak());
            
            %domObj=Image(which('Encabezado.png'));          % create an image object
            %domObj.Height='40mm';
            %domObj.Width='180mm';
            %append(doc,domObj);
            paraObj21=Paragraph('Ángulos medios de torsión del tronco: ');
            append(doc,paraObj21);
            paraObj17=Paragraph('                 - Máximo ángulo de torsión (grados): ');
            paraObj17.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj17=Text(ymax_beta_tr);
            append(paraObj17,textObj17);
            append(doc, paraObj17);
            paraObj18=Paragraph('                 - Minimo ángulo de torsión (grados): ');
            paraObj18.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj18=Text(ymin_beta_tr);
            append(paraObj18,textObj18);
            append(doc, paraObj18);    
            append(doc,LineBreak());
            paraObj22=Paragraph('Ángulos medios de extensión del tronco: ');
            append(doc,paraObj22);
            paraObj19=Paragraph('                 - Máximo ángulo de extensión (grados): ');
            paraObj19.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj19=Text(ymax_gamma_tr);
            append(paraObj19,textObj19);
            append(doc, paraObj19);
            paraObj20=Paragraph('                 - Minimo ángulo de extensión (grados): ');
            paraObj20.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj20=Text(ymin_gamma_tr);
            append(paraObj20,textObj20);
            append(doc, paraObj20);    
            append(doc,LineBreak());
            
            load angulostronco.jpeg
            imageObj=Image('angulostronco.jpeg');
            imageObj.Height='110mm';
            imageObj.Width='150mm';
            append(doc,imageObj);

            append(doc,LineBreak());
            paraObj23=Paragraph('Ángulos medios de elevación del hombro: ');
            append(doc,paraObj23);
            paraObj24=Paragraph('                 - Máximo ángulo de elevación (grados): ');
            paraObj24.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj24=Text(ymax_alpha_hombro);
            append(paraObj24,textObj24);
            append(doc, paraObj24);
            paraObj25=Paragraph('                 - Mínimo ángulo de elevación (grados): ');
            paraObj25.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj25=Text(ymin_alpha_hombro);
            append(paraObj25,textObj25);
            append(doc, paraObj25);    
            append(doc,LineBreak());
            paraObj26=Paragraph('Ángulos medios de plano de elevación del hombro: ');
            append(doc,paraObj26);
            paraObj26=Paragraph('                 - Máximo ángulo de plano elevación (grados): ');
            paraObj26.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj26=Text(ymax_beta_hombro);
            append(paraObj26,textObj26);
            append(doc, paraObj26);
            paraObj27=Paragraph('                 - Minimo ángulo de plano de elevación (grados): ');
            paraObj27.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj27=Text(ymin_beta_hombro);
            append(paraObj27,textObj27);
            append(doc, paraObj27);    
            append(doc,LineBreak());
            paraObj28=Paragraph('Ángulos medios de rotación interna del hombro: ');
            append(doc,paraObj28);
            paraObj29=Paragraph('                 - Máximo ángulo de rotación (grados): ');
            paraObj29.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj29=Text(ymax_gamma_hombro);
            append(paraObj29,textObj29);
            append(doc, paraObj29);
            paraObj30=Paragraph('                 - Minimo ángulo de rotación (grados): ');
            paraObj30.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj30=Text(ymin_gamma_hombro);
            append(paraObj30,textObj30);
            append(doc, paraObj30);    
            append(doc,LineBreak());
            
            load anguloshombro.jpeg
            imageObj2=Image('anguloshombro.jpeg');
            imageObj2.Height='110mm';
            imageObj2.Width='150mm';
            append(doc,imageObj2);

%             append(doc,LineBreak());
%             paraObj31=Paragraph('Ángulos medios de aducción del codo: ');
%             append(doc,paraObj31);
%             paraObj32=Paragraph('                 - Máximo ángulo de aducción (grados): ');
%             paraObj32.Style={HAlign('Left'), WhiteSpace('pre')};
%             textObj32=Text(ymax_alpha_codo);
%             append(paraObj32,textObj32);
%             append(doc, paraObj32);
%             paraObj33=Paragraph('                 - Mínimo ángulo de aducción (grados): ');
%             paraObj33.Style={HAlign('Left'), WhiteSpace('pre')};
%             textObj33=Text(ymin_alpha_codo);
%             append(paraObj33,textObj33);
%             append(doc, paraObj33);    
            append(doc,LineBreak());
            paraObj34=Paragraph('Ángulos medios de rotación interna del codo: ');
            append(doc,paraObj34);
            paraObj34=Paragraph('                 - Máximo ángulo de rotación interna (grados): ');
            paraObj34.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj34=Text(ymax_beta_codo);
            append(paraObj34,textObj34);
            append(doc, paraObj34);
            paraObj35=Paragraph('                 - Minimo ángulo de rotación interna (grados): ');
            paraObj35.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj35=Text(ymin_beta_codo);
            append(paraObj35,textObj35);
            append(doc, paraObj35);    
            append(doc,LineBreak());
            paraObj36=Paragraph('Ángulos medios de flexión del codo: ');
            append(doc,paraObj36);
            paraObj37=Paragraph('                 - Máximo ángulo de flexión (grados): ');
            paraObj37.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj37=Text(ymax_gamma_codo);
            append(paraObj37,textObj37);
            append(doc, paraObj37);
            paraObj38=Paragraph('                 - Minimo ángulo de flexión (grados): ');
            paraObj38.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj38=Text(ymin_gamma_codo);
            append(paraObj38,textObj38);
            append(doc, paraObj38);    
            append(doc,LineBreak());
            
            load anguloscodo.jpeg
            imageObj3=Image('anguloscodo.jpeg');
            imageObj3.Height='110mm';
            imageObj3.Width='150mm';
            append(doc,imageObj3);
            
            append(doc,LineBreak());
            paraObj39=Paragraph('Ángulos medios de desviación ulnar de la muñeca: ');
            append(doc,paraObj39);
            paraObj40=Paragraph('                 - Máximo ángulo de desviación ulnar (grados): ');
            paraObj40.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj40=Text(ymax_alpha_muneca);
            append(paraObj40,textObj40);
            append(doc, paraObj40);
            paraObj41=Paragraph('                 - Mínimo ángulo de desviación ulnar (grados): ');
            paraObj41.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj41=Text(ymin_alpha_muneca);
            append(paraObj41,textObj41);
            append(doc, paraObj41);    
            append(doc,LineBreak());
            paraObj42=Paragraph('Ángulos medios de rotación interna de la muñeca: ');
            append(doc,paraObj42);
            paraObj42=Paragraph('                 - Máximo ángulo de rotación interna (grados): ');
            paraObj42.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj42=Text(ymax_beta_muneca);
            append(paraObj42,textObj42);
            append(doc, paraObj42);
            paraObj43=Paragraph('                 - Minimo ángulo de rotación interna (grados): ');
            paraObj43.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj43=Text(ymin_beta_muneca);
            append(paraObj43,textObj43);
            append(doc, paraObj43);    
            append(doc,LineBreak());
            paraObj44=Paragraph('Ángulos medios de flexión de la muñeca: ');
            append(doc,paraObj44);
            paraObj45=Paragraph('                 - Máximo ángulo de flexión (grados): ');
            paraObj45.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj45=Text(ymax_gamma_muneca);
            append(paraObj45,textObj45);
            append(doc, paraObj45);
            paraObj46=Paragraph('                 - Minimo ángulo de flexión (grados): ');
            paraObj46.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj46=Text(ymin_gamma_muneca);
            append(paraObj46,textObj46);
            append(doc, paraObj46);    
            append(doc,LineBreak());
            
            load angulosmuneca.jpeg
            imageObj4=Image('angulosmuneca.jpeg');
            imageObj4.Height='110mm';
            imageObj4.Width='150mm';
            append(doc,imageObj4);
            
            paraObj47=Paragraph('Los resultados cinéticos del ensayo son: ');
            append(doc,paraObj47); 
            paraObj47.Style={Bold(true)};
            append(doc,LineBreak());
            paraObj48=Paragraph('Fuerzas medias del hombro en x: ');
            append(doc,paraObj48);
            paraObj57=Paragraph('                 - Máxima fuerza en componente x (Newtons): ');
            paraObj57.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj57=Text(ymax_fuerzax_hombro);
            append(paraObj57,textObj57);
            append(doc, paraObj57);
            paraObj49=Paragraph('                 - Mínima fuerza en componente x (Newtons): ');
            paraObj49.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj49=Text(ymin_fuerzax_hombro);
            append(paraObj49,textObj49);
            append(doc, paraObj49);    
            append(doc,LineBreak());
            paraObj50=Paragraph('Fuerzas medias del hombro en y: ');
            append(doc,paraObj50);
            paraObj51=Paragraph('                 - Máxima fuerza en componente y (Newtons): ');
            paraObj51.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj51=Text(ymax_fuerzay_hombro);
            append(paraObj51,textObj51);
            append(doc, paraObj51);
            paraObj52=Paragraph('                 - Mínima fuerza en componente y (Newtons): ');
            paraObj52.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj52=Text(ymin_fuerzay_hombro);
            append(paraObj52,textObj52);
            append(doc, paraObj52);    
            append(doc,LineBreak());
            paraObj53=Paragraph('Fuerzas medias del hombro en z: ');
            append(doc,paraObj53);
            paraObj54=Paragraph('                 - Máxima fuerza en componente z (Newtons): ');
            paraObj54.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj54=Text(ymax_fuerzaz_hombro);
            append(paraObj54,textObj54);
            append(doc, paraObj54);
            paraObj55=Paragraph('                 - Mínima fuerza en componente z (Newtons): ');
            paraObj55.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj55=Text(ymin_fuerzaz_hombro);
            append(paraObj55,textObj55);
            append(doc, paraObj55);    
            append(doc,LineBreak());
            
            load fuerzashombro.jpeg
            imageObj5=Image('fuerzashombro.jpeg');
            imageObj5.Height='110mm';
            imageObj5.Width='150mm';
            append(doc,imageObj5);
            
            append(doc,LineBreak());
            paraObj58=Paragraph('Fuerzas medias del codo en x: ');
            append(doc,paraObj58);
            paraObj59=Paragraph('                 - Máxima fuerza en componente x (Newtons): ');
            paraObj59.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj59=Text(ymax_fuerzax_codo);
            append(paraObj59,textObj59);
            append(doc, paraObj59);
            paraObj60=Paragraph('                 - Mínima fuerza en componente x (Newtons): ');
            paraObj60.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj60=Text(ymin_fuerzax_codo);
            append(paraObj60,textObj60);
            append(doc, paraObj60);    
            append(doc,LineBreak());
            paraObj61=Paragraph('Fuerzas medias del codo en y: ');
            append(doc,paraObj61);
            paraObj62=Paragraph('                 - Máxima fuerza en componente y (Newtons): ');
            paraObj62.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj62=Text(ymax_fuerzay_codo);
            append(paraObj62,textObj62);
            append(doc, paraObj62);
            paraObj63=Paragraph('                 - Mínima fuerza en componente y (Newtons): ');
            paraObj63.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj63=Text(ymin_fuerzay_codo);
            append(paraObj63,textObj63);
            append(doc, paraObj63);    
            append(doc,LineBreak());
            paraObj64=Paragraph('Fuerzas medias del codo en z: ');
            append(doc,paraObj64);
            paraObj65=Paragraph('                 - Máxima fuerza en componente z (Newtons): ');
            paraObj65.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj65=Text(ymax_fuerzaz_codo);
            append(paraObj65,textObj65);
            append(doc, paraObj65);
            paraObj66=Paragraph('                 - Mínima fuerza en componente z (Newtons): ');
            paraObj66.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj66=Text(ymin_fuerzaz_codo);
            append(paraObj66,textObj66);
            append(doc, paraObj66);    
            append(doc,LineBreak());
            
            load fuerzascodo.jpeg
            imageObj6=Image('fuerzascodo.jpeg');
            imageObj6.Height='110mm';
            imageObj6.Width='150mm';
            append(doc,imageObj6);
            
            append(doc,LineBreak());
            paraObj67=Paragraph('Fuerzas medias de la muñeca en x: ');
            append(doc,paraObj67);
            paraObj68=Paragraph('                 - Máxima fuerza en componente x (Newtons): ');
            paraObj68.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj68=Text(ymax_fuerzax_muneca);
            append(paraObj68,textObj68);
            append(doc, paraObj68);
            paraObj69=Paragraph('                 - Mínima fuerza en componente x (Newtons): ');
            paraObj69.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj69=Text(ymin_fuerzax_muneca);
            append(paraObj69,textObj69);
            append(doc, paraObj69);    
            append(doc,LineBreak());
            paraObj70=Paragraph('Fuerzas medias de la muñeca en y: ');
            append(doc,paraObj70);
            paraObj71=Paragraph('                 - Máxima fuerza en componente y (Newtons): ');
            paraObj71.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj71=Text(ymax_fuerzay_muneca);
            append(paraObj71,textObj71);
            append(doc, paraObj71);
            paraObj72=Paragraph('                 - Mínima fuerza en componente y (Newtons): ');
            paraObj72.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj72=Text(ymin_fuerzay_muneca);
            append(paraObj72,textObj72);
            append(doc, paraObj72);    
            append(doc,LineBreak());
            paraObj73=Paragraph('Fuerzas medias de la muñeca en z: ');
            append(doc,paraObj73);
            paraObj74=Paragraph('                 - Máxima fuerza en componente z (Newtons): ');
            paraObj74.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj74=Text(ymax_fuerzaz_muneca);
            append(paraObj74,textObj74);
            append(doc, paraObj74);
            paraObj75=Paragraph('                 - Mínima fuerza en componente z (Newtons): ');
            paraObj75.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj75=Text(ymin_fuerzaz_muneca);
            append(paraObj75,textObj75);
            append(doc, paraObj75);    
            append(doc,LineBreak());
            
            load fuerzasmuneca.jpeg
            imageObj6=Image('fuerzasmuneca.jpeg');
            imageObj6.Height='110mm';
            imageObj6.Width='150mm';
            append(doc,imageObj6);
            
            append(doc,LineBreak());
            paraObj76=Paragraph('Momentos medios en el hombro en x: ');
            append(doc,paraObj76);
            paraObj77=Paragraph('                 - Máximo momento en componente x (Newtons x metro): ');
            paraObj77.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj77=Text(ymax_momentox_hombro);
            append(paraObj77,textObj77);
            append(doc, paraObj77);
            paraObj78=Paragraph('                 - Mínimo momento en componente x (Newtons x metro): ');
            paraObj78.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj78=Text(ymin_momentox_hombro);
            append(paraObj78,textObj78);
            append(doc, paraObj78);    
            append(doc,LineBreak());
            paraObj79=Paragraph('Momentos medios en el hombro en y: ');
            append(doc,paraObj79);
            paraObj80=Paragraph('                 - Máximo momento en componente y (Newtons x metro): ');
            paraObj80.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj80=Text(ymax_momentoy_hombro);
            append(paraObj80,textObj80);
            append(doc, paraObj80);
            paraObj81=Paragraph('                 - Mínimo momento en componente y (Newtons x metro): ');
            paraObj81.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj81=Text(ymin_momentoy_hombro);
            append(paraObj81,textObj81);
            append(doc, paraObj81);    
            append(doc,LineBreak());
            paraObj82=Paragraph('Momentos medios en el hombro en z: ');
            append(doc,paraObj82);
            paraObj83=Paragraph('                 - Máximo momento en componente z (Newtons x metro): ');
            paraObj83.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj83=Text(ymax_momentoz_hombro);
            append(paraObj83,textObj83);
            append(doc, paraObj83);
            paraObj84=Paragraph('                 - Mínimo momento en componente z (Newtons x metro): ');
            paraObj84.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj84=Text(ymin_momentoz_hombro);
            append(paraObj84,textObj84);
            append(doc, paraObj84);    
            append(doc,LineBreak());
            
            load momentoshombro.jpeg
            imageObj7=Image('momentoshombro.jpeg');
            imageObj7.Height='110mm';
            imageObj7.Width='150mm';
            append(doc,imageObj7);
            
            append(doc,LineBreak());
            paraObj85=Paragraph('Momentos medios en el codo en x: ');
            append(doc,paraObj85);
            paraObj86=Paragraph('                 - Máximo momento en componente x (Newtons x metro): ');
            paraObj86.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj86=Text(ymax_momentox_codo);
            append(paraObj86,textObj86);
            append(doc, paraObj86);
            paraObj87=Paragraph('                 - Mínimo momento en componente x (Newtons x metro): ');
            paraObj87.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj87=Text(ymin_momentox_codo);
            append(paraObj87,textObj87);
            append(doc, paraObj87);    
            append(doc,LineBreak());
            paraObj88=Paragraph('Momentos medios en el codo en y: ');
            append(doc,paraObj88);
            paraObj88=Paragraph('                 - Máximo momento en componente y (Newtons x metro): ');
            paraObj88.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj88=Text(ymax_momentoy_codo);
            append(paraObj88,textObj88);
            append(doc, paraObj88);
            paraObj89=Paragraph('                 - Mínimo momento en componente y (Newtons x metro): ');
            paraObj89.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj89=Text(ymin_momentoy_codo);
            append(paraObj89,textObj89);
            append(doc, paraObj89);    
            append(doc,LineBreak());
            paraObj90=Paragraph('Momentos medios en el codo en z: ');
            append(doc,paraObj90);
            paraObj91=Paragraph('                 - Máximo momento en componente z (Newtons x metro): ');
            paraObj91.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj91=Text(ymax_momentoz_codo);
            append(paraObj91,textObj91);
            append(doc, paraObj91);
            paraObj92=Paragraph('                 - Mínimo momento en componente z (Newtons x metro): ');
            paraObj92.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj92=Text(ymin_momentoz_codo);
            append(paraObj92,textObj92);
            append(doc, paraObj92);    
            append(doc,LineBreak());
            
            load momentoscodo.jpeg
            imageObj8=Image('momentoscodo.jpeg');
            imageObj8.Height='110mm';
            imageObj8.Width='150mm';
            append(doc,imageObj8);
            
            append(doc,LineBreak());
            paraObj93=Paragraph('Momentos medios en la muñeca en x: ');
            append(doc,paraObj93);
            paraObj94=Paragraph('                 - Máximo momento en componente x (Newtons x metro): ');
            paraObj94.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj94=Text(ymax_momentox_muneca);
            append(paraObj94,textObj94);
            append(doc, paraObj94);
            paraObj95=Paragraph('                 - Mínimo momento en componente x (Newtons x metro): ');
            paraObj95.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj95=Text(ymin_momentox_muneca);
            append(paraObj95,textObj95);
            append(doc, paraObj95);    
            append(doc,LineBreak());
            paraObj96=Paragraph('Momentos medios en la muñeca en y: ');
            append(doc,paraObj96);
            paraObj96=Paragraph('                 - Máximo momento en componente y (Newtons x metro): ');
            paraObj96.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj96=Text(ymax_momentoy_muneca);
            append(paraObj96,textObj96);
            append(doc, paraObj96);
            paraObj97=Paragraph('                 - Mínimo momento en componente y (Newtons x metro): ');
            paraObj97.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj97=Text(ymin_momentoy_muneca);
            append(paraObj97,textObj97);
            append(doc, paraObj97);    
            append(doc,LineBreak());
            paraObj98=Paragraph('Momentos medios en la muñeca en z: ');
            append(doc,paraObj98);
            paraObj99=Paragraph('                 - Máximo momento en componente z (Newtons x metro): ');
            paraObj99.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj99=Text(ymax_momentoz_muneca);
            append(paraObj99,textObj99);
            append(doc, paraObj99);
            paraObj100=Paragraph('                 - Mínimo momento en componente z (Newtons x metro): ');
            paraObj100.Style={HAlign('Left'), WhiteSpace('pre')};
            textObj100=Text(ymin_momentoz_muneca);
            append(paraObj100,textObj100);
            append(doc, paraObj100);    
            append(doc,LineBreak());
            
            load momentosmuneca.jpeg
            imageObj9=Image('momentosmuneca.jpeg');
            imageObj9.Height='110mm';
            imageObj9.Width='150mm';
            append(doc,imageObj9);
            %% Close and view the report
            close (doc); %close document and write to disk
            %docview(doc.OutputPath);            %open document in in-built PDF Viewe
            system('libreoffice propulsion.docx');
            %winopen mydoc.pdf
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.BalanceolateralderechoLabel.Visible='on';
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.AdduccinLabel.Visible='on';
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.TorsinhaciaizquierdaLabel.Visible='on';
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.HaciaadelanteLabel.Visible='on';
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.EstevalorsiempreesceroLabel.Visible='on';
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.RotacininternaLabel_2.Visible='on';
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.DesviacinulnarLabel.Visible='on';
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.RotacininternaLabel_3.Visible='on';
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.FlexinLabel_2.Visible='on';
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.FlexinLabel.Visible='on';
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.RotacininternaLabel.Visible='on';
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.ExtensinhaciadetrsLabel.Visible='on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EstudiodePropulsionUIFigure = uifigure;
            app.EstudiodePropulsionUIFigure.Name = 'Estudio de Propulsion';
            app.EstudiodePropulsionUIFigure.SizeChangedFcn = createCallbackFcn(app, @EstudiodePropulsionUIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.EstudiodePropulsionUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CargadedatosTab = uitab(app.TabGroup);
            app.CargadedatosTab.SizeChangedFcn = createCallbackFcn(app, @CargadedatosTabSizeChanged, true);
            app.CargadedatosTab.Title = 'Carga de datos';
            app.CargaDatosSmartwheelButton = uibutton(app.CargadedatosTab, 'push');
            app.CargaDatosSmartwheelButton.ButtonPushedFcn = createCallbackFcn(app, @CargaDatosSmartwheelButtonPushed, true);
            app.CargaDatosSmartwheelButton.Text = 'Carga Datos Smartwheel';
            app.CargaDatosKinescanButton = uibutton(app.CargadedatosTab, 'push');
            app.CargaDatosKinescanButton.ButtonPushedFcn = createCallbackFcn(app, @CargaDatosKinescanButtonPushed, true);
            app.CargaDatosKinescanButton.Text = 'Carga Datos Kinescan';
            app.RealizarclculosButton = uibutton(app.CargadedatosTab, 'push');
            app.RealizarclculosButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarclculosButtonPushed2, true);
            app.RealizarclculosButton.Text = 'Realizar cálculos';
            app.PosicionesTab = uitab(app.TabGroup);
            app.PosicionesTab.Title = 'Posiciones';
            app.Tronco = uibutton(app.PosicionesTab, 'push');
            app.Tronco.ButtonPushedFcn = createCallbackFcn(app, @TroncoButtonPushed, true);
            app.Tronco.Text = 'Tronco';
            app.UIAxes = uiaxes(app.PosicionesTab);
            title(app.UIAxes, 'Balanceo')
            app.UIAxes_2 = uiaxes(app.PosicionesTab);
            title(app.UIAxes_2, 'Torsión')
            app.UIAxes_3 = uiaxes(app.PosicionesTab);
            title(app.UIAxes_3, 'Extensión')
            app.Hombro = uibutton(app.PosicionesTab, 'push');
            app.Hombro.ButtonPushedFcn = createCallbackFcn(app, @HombroButtonPushed, true);
            app.Hombro.Text = 'Hombro';
            app.UIAxes_4 = uiaxes(app.PosicionesTab);
            title(app.UIAxes_4, 'Elevación')
            app.UIAxes_5 = uiaxes(app.PosicionesTab);
            title(app.UIAxes_5, 'Plano de elevación')
            app.UIAxes_6 = uiaxes(app.PosicionesTab);
            title(app.UIAxes_6, 'Rotación interna')
            app.Codo = uibutton(app.PosicionesTab, 'push');
            app.Codo.ButtonPushedFcn = createCallbackFcn(app, @CodoButtonPushed, true);
            app.Codo.Text = 'Codo';
            app.UIAxes_7 = uiaxes(app.PosicionesTab);
            title(app.UIAxes_7, 'Aducción')
            app.UIAxes_7.XLim = [0 1];
            app.UIAxes_8 = uiaxes(app.PosicionesTab);
            title(app.UIAxes_8, 'Rotación interna')
            app.UIAxes_9 = uiaxes(app.PosicionesTab);
            title(app.UIAxes_9, 'Flexión')
            app.Muneca = uibutton(app.PosicionesTab, 'push');
            app.Muneca.ButtonPushedFcn = createCallbackFcn(app, @MunecaButtonPushed, true);
            app.Muneca.Text = 'Muñeca';
            app.UIAxes_10 = uiaxes(app.PosicionesTab);
            title(app.UIAxes_10, 'Desviación Ulnar')
            app.UIAxes_11 = uiaxes(app.PosicionesTab);
            title(app.UIAxes_11, 'Rotación interna')
            app.UIAxes_12 = uiaxes(app.PosicionesTab);
            title(app.UIAxes_12, 'Flexión')
            app.Button = uibutton(app.PosicionesTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.BalanceolateralderechoLabel = uilabel(app.PosicionesTab);
            app.BalanceolateralderechoLabel.Text = 'Balanceo lateral derecho (+)';
            app.Button_2 = uibutton(app.PosicionesTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '?';
            app.Button_3 = uibutton(app.PosicionesTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '?';
            app.Button_4 = uibutton(app.PosicionesTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '?';
            app.Button_5 = uibutton(app.PosicionesTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '?';
            app.Button_6 = uibutton(app.PosicionesTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '?';
            app.Button_7 = uibutton(app.PosicionesTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '?';
            app.Button_8 = uibutton(app.PosicionesTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '?';
            app.Button_9 = uibutton(app.PosicionesTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '?';
            app.Button_10 = uibutton(app.PosicionesTab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '?';
            app.Button_11 = uibutton(app.PosicionesTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '?';
            app.TorsinhaciaizquierdaLabel = uilabel(app.PosicionesTab);
            app.TorsinhaciaizquierdaLabel.Text = 'Torsión hacia izquierda (+)';
            app.ExtensinhaciadetrsLabel = uilabel(app.PosicionesTab);
            app.ExtensinhaciadetrsLabel.Text = 'Extensión hacia detrás (+)';
            app.Button_12 = uibutton(app.PosicionesTab, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '?';
            app.AdduccinLabel = uilabel(app.PosicionesTab);
            app.AdduccinLabel.Text = 'Adducción (+)';
            app.HaciaadelanteLabel = uilabel(app.PosicionesTab);
            app.HaciaadelanteLabel.Text = 'Hacia adelante (+)';
            app.RotacininternaLabel = uilabel(app.PosicionesTab);
            app.RotacininternaLabel.Text = 'Rotación interna (+)';
            app.EstevalorsiempreesceroLabel = uilabel(app.PosicionesTab);
            app.EstevalorsiempreesceroLabel.Text = 'Este valor siempre es cero';
            app.RotacininternaLabel_2 = uilabel(app.PosicionesTab);
            app.RotacininternaLabel_2.Text = 'Rotación interna (+)';
            app.FlexinLabel = uilabel(app.PosicionesTab);
            app.FlexinLabel.Text = 'Flexión (+)';
            app.DesviacinulnarLabel = uilabel(app.PosicionesTab);
            app.DesviacinulnarLabel.Text = 'Desviación ulnar (+)';
            app.RotacininternaLabel_3 = uilabel(app.PosicionesTab);
            app.RotacininternaLabel_3.Text = 'Rotación interna (+)';
            app.FlexinLabel_2 = uilabel(app.PosicionesTab);
            app.FlexinLabel_2.Text = 'Flexión (+)';
            app.FuerzasTab = uitab(app.TabGroup);
            app.FuerzasTab.Title = 'Fuerzas';
            app.Hombro_2 = uibutton(app.FuerzasTab, 'push');
            app.Hombro_2.ButtonPushedFcn = createCallbackFcn(app, @Hombro_2ButtonPushed, true);
            app.Hombro_2.Text = 'Hombro';
            app.UIAxes_16 = uiaxes(app.FuerzasTab);
            title(app.UIAxes_16, 'Fuerza componente x')
            app.UIAxes_17 = uiaxes(app.FuerzasTab);
            title(app.UIAxes_17, 'Fuerza componente y')
            app.UIAxes_18 = uiaxes(app.FuerzasTab);
            title(app.UIAxes_18, 'Fuerza componente z')
            app.Codo_2 = uibutton(app.FuerzasTab, 'push');
            app.Codo_2.ButtonPushedFcn = createCallbackFcn(app, @Codo_2ButtonPushed, true);
            app.Codo_2.Text = 'Codo';
            app.UIAxes_19 = uiaxes(app.FuerzasTab);
            title(app.UIAxes_19, 'Fuerza componente x')
            app.UIAxes_20 = uiaxes(app.FuerzasTab);
            title(app.UIAxes_20, 'Fuerza componente y')
            app.UIAxes_21 = uiaxes(app.FuerzasTab);
            title(app.UIAxes_21, 'Fuerza componente z')
            app.Muneca_2 = uibutton(app.FuerzasTab, 'push');
            app.Muneca_2.ButtonPushedFcn = createCallbackFcn(app, @Muneca_2ButtonPushed, true);
            app.Muneca_2.Text = 'Muñeca';
            app.UIAxes_22 = uiaxes(app.FuerzasTab);
            title(app.UIAxes_22, 'Fuerza componente x')
            app.UIAxes_23 = uiaxes(app.FuerzasTab);
            title(app.UIAxes_23, 'Fuerza componente y')
            app.UIAxes_24 = uiaxes(app.FuerzasTab);
            title(app.UIAxes_24, 'Fuerza componente z')
            app.MomentosTab = uitab(app.TabGroup);
            app.MomentosTab.Title = 'Momentos';
            app.Hombro_3 = uibutton(app.MomentosTab, 'push');
            app.Hombro_3.ButtonPushedFcn = createCallbackFcn(app, @Hombro_3ButtonPushed, true);
            app.Hombro_3.Text = 'Hombro';
            app.UIAxes_25 = uiaxes(app.MomentosTab);
            title(app.UIAxes_25, 'Momento componente x')
            app.UIAxes_26 = uiaxes(app.MomentosTab);
            title(app.UIAxes_26, 'Momento componente y')
            app.UIAxes_27 = uiaxes(app.MomentosTab);
            title(app.UIAxes_27, 'Momento componente z')
            app.Codo_3 = uibutton(app.MomentosTab, 'push');
            app.Codo_3.ButtonPushedFcn = createCallbackFcn(app, @Codo_3ButtonPushed, true);
            app.Codo_3.Text = 'Codo';
            app.UIAxes_28 = uiaxes(app.MomentosTab);
            title(app.UIAxes_28, 'Momento componente x')
            app.UIAxes_29 = uiaxes(app.MomentosTab);
            title(app.UIAxes_29, 'Momento componente y')
            app.UIAxes_30 = uiaxes(app.MomentosTab);
            title(app.UIAxes_30, 'Momento componente z')
            app.Muneca_3 = uibutton(app.MomentosTab, 'push');
            app.Muneca_3.ButtonPushedFcn = createCallbackFcn(app, @Muneca_3ButtonPushed, true);
            app.Muneca_3.Text = 'Muñeca';
            app.UIAxes_31 = uiaxes(app.MomentosTab);
            title(app.UIAxes_31, 'Momento componente x')
            app.UIAxes_32 = uiaxes(app.MomentosTab);
            title(app.UIAxes_32, 'Momento componente y')
            app.UIAxes_33 = uiaxes(app.MomentosTab);
            title(app.UIAxes_33, 'Momento componente z')
            app.InformeTab = uitab(app.TabGroup);
            app.InformeTab.Title = 'Informe';
            app.GenerarinformeButton_2 = uibutton(app.InformeTab, 'push');
            app.GenerarinformeButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerarinformeButton_2Pushed, true);
            app.GenerarinformeButton_2.Text = 'Generar informe';
            app.NombredelpacienteEditFieldLabel = uilabel(app.InformeTab);
            app.NombredelpacienteEditFieldLabel.Text = 'Nombre del paciente:';
            app.NombredelpacienteEditField = uieditfield(app.InformeTab, 'text');
            app.FechadelestudioEditFieldLabel = uilabel(app.InformeTab);
            app.FechadelestudioEditFieldLabel.Text = 'Fecha del estudio:';
            app.FechadelestudioEditField = uieditfield(app.InformeTab, 'text');
            app.HNCLabel = uilabel(app.InformeTab);
            app.HNCLabel.Text = 'HNC:';
            app.HNCEditField = uieditfield(app.InformeTab, 'text');
            app.PlantaEditFieldLabel = uilabel(app.InformeTab);
            app.PlantaEditFieldLabel.Text = 'Planta:';
            app.PlantaEditField = uieditfield(app.InformeTab, 'text');
            app.NiveldelesionEditFieldLabel = uilabel(app.InformeTab);
            app.NiveldelesionEditFieldLabel.Text = 'Nivel de lesion:';
            app.NiveldelesionEditField = uieditfield(app.InformeTab, 'text');
            app.ASIAEditFieldLabel = uilabel(app.InformeTab);
            app.ASIAEditFieldLabel.Text = 'ASIA:';
            app.ASIAEditField = uieditfield(app.InformeTab, 'text');
            app.CodigopacienteEditFieldLabel = uilabel(app.InformeTab);
            app.CodigopacienteEditFieldLabel.Text = 'Codigo paciente:';
            app.CodigopacienteEditField = uieditfield(app.InformeTab, 'text');
            app.PesoEditFieldLabel = uilabel(app.InformeTab);
            app.PesoEditFieldLabel.Text = 'Peso:';
            app.PesoEditField = uieditfield(app.InformeTab, 'text');
            app.AlturaEditFieldLabel = uilabel(app.InformeTab);
            app.AlturaEditFieldLabel.Text = 'Altura:';
            app.AlturaEditField = uieditfield(app.InformeTab, 'text');
            app.EdadEditFieldLabel = uilabel(app.InformeTab);
            app.EdadEditFieldLabel.Text = 'Edad:';
            app.EdadEditField = uieditfield(app.InformeTab, 'text');
            app.MotivodeconsultaEditFieldLabel = uilabel(app.InformeTab);
            app.MotivodeconsultaEditFieldLabel.Text = 'Motivo de consulta:';
            app.MotivodeconsultaEditField = uieditfield(app.InformeTab, 'text');
            app.FacultativosolicitanteEditFieldLabel = uilabel(app.InformeTab);
            app.FacultativosolicitanteEditFieldLabel.Text = 'Facultativo solicitante:';
            app.FacultativosolicitanteEditField = uieditfield(app.InformeTab, 'text');
            app.DATOSDELPACIENTELabel = uilabel(app.InformeTab);
            app.DATOSDELPACIENTELabel.Text = 'DATOS DEL PACIENTE';
            app.DATOSDELESTUDIOLabel = uilabel(app.InformeTab);
            app.DATOSDELESTUDIOLabel.Text = 'DATOS DEL ESTUDIO';
            app.DescripcindelensayoEditFieldLabel = uilabel(app.InformeTab);
            app.DescripcindelensayoEditFieldLabel.Text = 'Descripción del ensayo:';
            app.DescripcindelensayoEditField = uieditfield(app.InformeTab, 'text');
            app.InformedelestudioEditFieldLabel = uilabel(app.InformeTab);
            app.InformedelestudioEditFieldLabel.Text = 'Informe del estudio:';
            app.InformedelestudioEditField = uieditfield(app.InformeTab, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EstudiodePropulsionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EstudiodePropulsionUIFigure)
        end
    end
end